classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SystemTab                      matlab.ui.container.Tab
        NumberofCustomersSpinner       matlab.ui.control.Spinner
        NumberofCustomersSpinnerLabel  matlab.ui.control.Label
        NumberofAttacksSpinner         matlab.ui.control.Spinner
        NumberofAttacksSpinnerLabel    matlab.ui.control.Label
        OutputstobeattackedNoselectionchoosesrandomoutputsListBox  matlab.ui.control.ListBox
        OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel  matlab.ui.control.Label
        LoadsystemfromafileemptyfieldusessettingsaboveLabel  matlab.ui.control.Label
        SystemInputFileEditField       matlab.ui.control.EditField
        SystemInputFileEditFieldLabel  matlab.ui.control.Label
        SystemParametersLabel          matlab.ui.control.Label
        SimulationTab                  matlab.ui.container.Tab
        InitialConditionsEditField     matlab.ui.control.EditField
        InitialConditionsEditFieldLabel  matlab.ui.control.Label
        SimulationTimesSlider          matlab.ui.control.Slider
        SimulationTimesSliderLabel     matlab.ui.control.Label
        SimulationParametersLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clearvars PDMO;
            numCustomers    = app.NumberofCustomersSpinner.Value;
            numAttacks      = app.NumberofAttacksSpinner.Value;
            attackedOutputs = app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Value;
            if isempty(attackedOutputs)
                attackedOutputs = "";
            end
            attackedOutputs = str2num(attackedOutputs);
            tspan           = [0 app.SimulationTimesSlider.Value];
            x0              = app.InitialConditionsEditField.Value;
            if isempty(x0)
                x0 = -0.5 + rand(numCustomers,1);
            else
                x0 = str2double(x0);
            end
            if size(x0,2) == 1
                x0 = repmat(x0,1,numCustomers);
            end
            assert(size(x0,2)==numCustomers,...
                "The amount of initial conditions (%d) does not equal the number of customers (%d)",...
                size(x0,2),numCustomers);
            inputFileName   = app.SystemInputFileEditField.Value;
            PDMO = PowerDistMO(numCustomers,numAttacks,attackedOutputs,inputFileName);
            
            [t,v,x] = PDMO.solve(tspan,x0);
            plot(app.UIAxes,t,[230 + 5*sin(t); v(:,:,1)]);
%             plot(app.UIAxes,t,230 + 5*sin(t),"LineWidth",0.5,'Color','black');
            grayColor = [.7 .7 .7];
            yline(app.UIAxes,230,'--','Color',grayColor)
            yline(app.UIAxes,230*1.1,'--','Color',grayColor)
            yline(app.UIAxes,230*0.9,'--','Color',grayColor)
            legend(app.UIAxes,{'v_0','v_1','v_2','v_3','v_4','v_5'})
            xlim(app.UIAxes,tspan)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Customer Voltages')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Voltage [V]')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.SystemParametersLabel = uilabel(app.SystemTab);
            app.SystemParametersLabel.Text = 'System Parameters';
            app.SystemInputFileEditFieldLabel = uilabel(app.SystemTab);
            app.SystemInputFileEditFieldLabel.Text = 'System Input  File';
            app.SystemInputFileEditField = uieditfield(app.SystemTab, 'text');
            app.LoadsystemfromafileemptyfieldusessettingsaboveLabel = uilabel(app.SystemTab);
            app.LoadsystemfromafileemptyfieldusessettingsaboveLabel.Text = 'Load system from a file (empty field uses settings above)';
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel = uilabel(app.SystemTab);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel.Text = {'Outputs to be attacked'; '(No selection  chooses'; 'random outputs, hold control to select multiple)'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox = uilistbox(app.SystemTab);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Multiselect = 'on';
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Value = {};
            app.NumberofAttacksSpinnerLabel = uilabel(app.SystemTab);
            app.NumberofAttacksSpinnerLabel.Text = 'Number of Attacks';
            app.NumberofAttacksSpinner = uispinner(app.SystemTab);
            app.NumberofAttacksSpinner.Limits = [0 50];
            app.NumberofAttacksSpinner.Value = 2;
            app.NumberofCustomersSpinnerLabel = uilabel(app.SystemTab);
            app.NumberofCustomersSpinnerLabel.Text = 'Number of Customers';
            app.NumberofCustomersSpinner = uispinner(app.SystemTab);
            app.NumberofCustomersSpinner.Limits = [0 50];
            app.NumberofCustomersSpinner.Value = 5;
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationParametersLabel = uilabel(app.SimulationTab);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.SimulationTimesSliderLabel = uilabel(app.SimulationTab);
            app.SimulationTimesSliderLabel.Text = {'Simulation '; 'Time [s]'};
            app.SimulationTimesSlider = uislider(app.SimulationTab);
            app.SimulationTimesSlider.Limits = [0 50];
            app.SimulationTimesSlider.Value = 10;
            app.InitialConditionsEditFieldLabel = uilabel(app.SimulationTab);
            app.InitialConditionsEditFieldLabel.Text = 'Initial Conditions';
            app.InitialConditionsEditField = uieditfield(app.SimulationTab, 'text');
            app.InitialConditionsEditField.Value = '0';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end